classdef Sums < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        StartGraphingButton            matlab.ui.control.Button
        SubIntervalsSliderLabel        matlab.ui.control.Label
        SubIntervalsSlider             matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        aboundEditFieldLabel           matlab.ui.control.Label
        aboundEditField                matlab.ui.control.NumericEditField
        bboundEditFieldLabel           matlab.ui.control.Label
        bboundEditField                matlab.ui.control.NumericEditField
        SumTypeButtonGroup             matlab.ui.container.ButtonGroup
        LeftEndpointButton             matlab.ui.control.RadioButton
        MidpointButton                 matlab.ui.control.RadioButton
        RightEndpointButton            matlab.ui.control.RadioButton
        TrapezoidalButton              matlab.ui.control.RadioButton
        ParabolicSimpsonsRuleButton    matlab.ui.control.RadioButton
        FunctionInputfxEditFieldLabel  matlab.ui.control.Label
        FunctionInputfxEditField       matlab.ui.control.EditField
        ResultLabel                    matlab.ui.control.Label
        YAxisPositionDropDownLabel     matlab.ui.control.Label
        YAxisPositionDropDown          matlab.ui.control.DropDown
    end


    properties (Access = private)
        lowerBound = 0 % a bound of reimann sum
        upperBound = 0 % b bound of reimann sum
        n = 0
        selections = [1 0 0 0 0]
        expression = 0
        validFunc = 0
        mode = 0 % will store index of selected radio button
        funcString = 'f(x)'
        validInput = 0
        funcMax = 0
        dx = 0
        rects = 0
        result = 0
        gObj = 0
    end

    methods (Access = private)
    
        function setBounds(app)
            % set graph axes range
            % maximize function on given interval ot get y axes range and set x to given a - b bounds
            syms x
            
            app.dx = (app.upperBound - app.lowerBound)/app.n;
            for i = app.lowerBound:app.dx:app.upperBound
                y = subs(app.expression, x, i);
                if y > app.funcMax
                    app.funcMax = y;
                end
            end
            % store function max on the interval as a double
            app.funcMax = double(app.funcMax);
        end
        
        
        function getRects(app) % collect sum results in an array to use for rect drawing later
            % The final estimate is obtained by summing the values in the array produced
            %app.rects = zeros(app.n);
            %disp(app.selections);
            syms x
            count = 1
            if app.selections(1) == 1
                disp("LEFT ENDPOINT");
                count = 1
                for i = app.lowerBound:app.dx:app.upperBound - app.dx
                    y = subs(app.expression, x, i);
                    app.rects(count) = double(y);
                    count = count + 1;
                end
                disp(app.rects);
                app.result = sum(app.rects) * app.dx;
            elseif app.selections(2) == 1
                disp("MIDPOINT");
                
                for i = app.lowerBound + app.dx / 2:app.dx:app.upperBound
                    y = subs(app.expression, x, i);
                    app.rects(count) = double(y);
                    count = count + 1;
                end
                disp(app.rects)
                disp(sum(app.rects));
                app.result = sum(app.rects) * app.dx;
            elseif app.selections(3) == 1
                disp("RIGHT ENDPOINT");
                
                for i = app.lowerBound + app.dx:app.dx:app.upperBound
                    y = subs(app.expression, x, i);
                    app.rects(count) = double(y);
                    count = count + 1;
                end
                disp(app.rects);
                disp(sum(app.rects));
                app.result = sum(app.rects) * app.dx;
            elseif app.selections(4) == 1
                disp("TRAPEZOIDAL");
                app.rects = [0 0; 0 0];
                for i = app.lowerBound:app.dx:app.upperBound - app.dx % grab y values calculate in different loop
                    yl = subs(app.expression, x, i);
                    yl = double(yl);
                    
                    yr = subs(app.expression, x, i + app.dx);
                    yr = double(yr);
                    
                    app.rects(count, :) = [yl yr];
                    count = count + 1;
                end
                disp(app.rects);
                %disp(sum(app.result));
                dims = size(app.rects);
                len = dims(1);
                app.result = 0;
                for j = 1:len
                    app.result = app.result + sum(app.rects(j, :));
                end
                app.result = app.result * (app.dx / 2);
            elseif app.selections(5) == 1
                disp("PARABOLIC");
            end
            %disp('yeet');
        end
        
        function drawLeftEnd(app)
            for i = 1:length(app.rects)
                pgon = polyshape([0 app.dx 0 app.dx],[0 0 app.rects(1) app.rects(1)]);
                rect = rectangle(app.UIAxes,'Position',[(i * app.dx - app.dx) 0 app.dx app.rects(i)],'FaceColor',[0 .5 .5]);
                %plot(app.UIAxes, pgon);
            end
        end
    
        
        function drawRects(app)
            for i = 1:length(app.rects)
                
                xOffset = (i * app.dx) - app.dx;
                pgon = polyshape([xOffset (xOffset + app.dx) (xOffset + app.dx) xOffset],[0 0 app.rects(i) app.rects(i)]);
                plot(app.UIAxes, pgon);
                %rect = rectangle(app.UIAxes,'Position',[x 0 app.dx app.rects(i)],'FaceColor',[0 .5 .5]);
            end
            str = sprintf('Result: %.4f', app.result);
            app.ResultLabel.Text = str;
        end
        
        function drawTrapezoids(app)
            dims = size(app.rects)
            len = dims(1);
            for i = 1:len
                yl = app.rects(i, 1);
                yr = app.rects(i, 2);
                xOffset = (i * app.dx) - app.dx;
                pgon = polyshape([xOffset (xOffset + app.dx) (xOffset + app.dx) xOffset],[0 0 yr yl]);
                plot(app.UIAxes, pgon);
            end
            str = sprintf('Result: %.4f', app.result);
            app.ResultLabel.Text = str;
        end
        
        function resetVals(app)
            app.rects = 0;
            app.result = 0;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.YAxisLocation = 'origin';
        end

        % Selection changed function: SumTypeButtonGroup
        function SumTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SumTypeButtonGroup.SelectedObject;
            app.selections = [app.LeftEndpointButton.Value, app.MidpointButton.Value, app.RightEndpointButton.Value, app.TrapezoidalButton.Value, app.ParabolicSimpsonsRuleButton.Value]
        end

        % Value changed function: SubIntervalsSlider
        function SubIntervalsSliderValueChanged(app, event)
            value = app.SubIntervalsSlider.Value;
            app.n = floor(value)
            app.Label.Text = num2str(app.n)
        end

        % Value changed function: aboundEditField
        function aboundEditFieldValueChanged(app, event)
            value = app.aboundEditField.Value;
            app.lowerBound = value
        end

        % Value changed function: bboundEditField
        function bboundEditFieldValueChanged(app, event)
            value = app.bboundEditField.Value;
            app.upperBound = value
        end

        % Button pushed function: StartGraphingButton
        function StartGraphingButtonPushed(app, event)
            app.validInput = 1
            if app.validFunc == 0
                uialert(app.UIFigure,'Invalid Function Expression', 'Invalid Function');
            else
                for i=1:app.selections
                    if app.selections(i) == 1
                        app.mode = app.selections(i)
                    end
                end
                if app.lowerBound == app.upperBound
                    app.validInput = 0
                    uialert(app.UIFigure, 'Please select Valid Bounds', 'Invalid Interval')
                end
                if app.n <= 0
                    app.validInput = 0
                    uialert(app.UIFigure, 'Please a number of sub intervals', 'Invalid Sub Intervals')
                end
                if app.validInput == 1
                    cla(app.UIAxes, 'reset');
                    app.resetVals();
                    
                    app.setBounds();
                    app.getRects();
                    hold(app.UIAxes, 'on');
                    fplot(app.UIAxes, app.expression)
                    %app.drawLeftEnd();
                    app.UIAxes.XAxisLocation = 'origin';
                    if app.selections(1) == 1 || app.selections(2) == 1 || app.selections(3) == 1
                        app.drawRects();
                    else
                        app.drawTrapezoids();
                    end
                    hold(app.UIAxes, 'off');
                    %rsums(app.expression, app.lowerBound, app.upperBound);
                end
            end
        end

        % Value changed function: FunctionInputfxEditField
        function FunctionInputfxEditFieldValueChanged(app, event)
            value = app.FunctionInputfxEditField.Value;
            try
                app.expression = str2sym(value)
                app.validFunc = 1
                
                x = 0
                eval(value) % check for valid function expression throws error if not
                
                %syms x
                %app.Label.Text = num2str(double(subs(app.expression, x, 3)))
            catch
                app.validFunc = 0
                uialert(app.UIFigure,'Invalid Function Expression', 'Invalid Function');
            end
        end

        % Value changed function: YAxisPositionDropDown
        function YAxisPositionDropDownValueChanged(app, event)
            value = app.YAxisPositionDropDown.Value;
            if value == 'Origin'
                app.UIAxes.YAxisLocation = 'origin';
            elseif value == 'Left'
                app.UIAxes.YAxisLocation = 'left';
            elseif value == 'Right'
                app.UIAxes.YAxisLocation = 'right';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YAxisLocation = 'origin';
            app.StartGraphingButton = uibutton(app.UIFigure, 'push');
            app.StartGraphingButton.ButtonPushedFcn = createCallbackFcn(app, @StartGraphingButtonPushed, true);
            app.StartGraphingButton.Text = 'Start Graphing';
            app.SubIntervalsSliderLabel = uilabel(app.UIFigure);
            app.SubIntervalsSliderLabel.Text = 'Sub Intervals';
            app.SubIntervalsSlider = uislider(app.UIFigure);
            app.SubIntervalsSlider.Limits = [0 50];
            app.SubIntervalsSlider.ValueChangedFcn = createCallbackFcn(app, @SubIntervalsSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.aboundEditFieldLabel = uilabel(app.UIFigure);
            app.aboundEditFieldLabel.Text = 'a bound';
            app.aboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.aboundEditField.RoundFractionalValues = 'on';
            app.aboundEditField.ValueChangedFcn = createCallbackFcn(app, @aboundEditFieldValueChanged, true);
            app.bboundEditFieldLabel = uilabel(app.UIFigure);
            app.bboundEditFieldLabel.Text = 'b bound';
            app.bboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.bboundEditField.RoundFractionalValues = 'on';
            app.bboundEditField.ValueChangedFcn = createCallbackFcn(app, @bboundEditFieldValueChanged, true);
            app.SumTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SumTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SumTypeButtonGroupSelectionChanged, true);
            app.SumTypeButtonGroup.Title = 'Sum Type';
            app.LeftEndpointButton = uiradiobutton(app.SumTypeButtonGroup);
            app.LeftEndpointButton.Text = 'Left Endpoint';
            app.LeftEndpointButton.Value = true;
            app.MidpointButton = uiradiobutton(app.SumTypeButtonGroup);
            app.MidpointButton.Text = 'Midpoint';
            app.RightEndpointButton = uiradiobutton(app.SumTypeButtonGroup);
            app.RightEndpointButton.Text = 'Right Endpoint';
            app.TrapezoidalButton = uiradiobutton(app.SumTypeButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.ParabolicSimpsonsRuleButton = uiradiobutton(app.SumTypeButtonGroup);
            app.ParabolicSimpsonsRuleButton.Text = 'Parabolic (Simpson''s Rule)';
            app.FunctionInputfxEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionInputfxEditFieldLabel.Text = 'Function Input f(x) = ';
            app.FunctionInputfxEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionInputfxEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionInputfxEditFieldValueChanged, true);
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result: 0 ';
            app.YAxisPositionDropDownLabel = uilabel(app.UIFigure);
            app.YAxisPositionDropDownLabel.Text = 'Y Axis Position';
            app.YAxisPositionDropDown = uidropdown(app.UIFigure);
            app.YAxisPositionDropDown.Items = {'Origin', 'Left', 'Right', ''};
            app.YAxisPositionDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisPositionDropDownValueChanged, true);
            app.YAxisPositionDropDown.Value = 'Origin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sums

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end